classdef PlaneStress < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        MeshOptionsLabel              matlab.ui.control.Label
        PlaneDimensionsLabel          matlab.ui.control.Label
        VerticalDivisionsEditField    matlab.ui.control.NumericEditField
        VerticalDivisionsEditFieldLabel  matlab.ui.control.Label
        HorizontalDivisionsEditField  matlab.ui.control.NumericEditField
        HorizontalDivisionsEditFieldLabel  matlab.ui.control.Label
        HeightEditField               matlab.ui.control.NumericEditField
        HeightEditFieldLabel          matlab.ui.control.Label
        WidthEditField_2              matlab.ui.control.NumericEditField
        WidthEditField_2Label         matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        NodeCoordinates % Description
        ElementNodes % Description
    end

    methods (Access = private)

        function results = graph(app)
            if app.WidthEditField_2.Value~=0 && app.HeightEditField.Value~=0
                [p,cl]=GenerateMesh(app.WidthEditField_2.Value,app.HeightEditField.Value,app.HorizontalDivisionsEditField.Value,app.VerticalDivisionsEditField.Value);
                hold(app.UIAxes, 'all');
                patch(app.UIAxes,'faces', cl, 'Vertices', p, 'facecolor', 'c', 'edgecolor', 'k');
                plot(app.UIAxes,p(1,:), p(2,:), 'o', 'color', 'k');
                app.NodeCoordinates=p;
                app.ElementNodes=cl;
              
            end


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: WidthEditField_2
        function WidthEditField_2ValueChanged(app, event)
            value = app.WidthEditField_2.Value;
            app.graph();
        end

        % Value changed function: HeightEditField
        function HeightEditFieldValueChanged(app, event)
            value = app.HeightEditField.Value;
            app.graph();
        end

        % Value changed function: HorizontalDivisionsEditField
        function HorizontalDivisionsEditFieldValueChanged(app, event)
            value = app.HorizontalDivisionsEditField.Value;
            app.graph();
        end

        % Value changed function: VerticalDivisionsEditField
        function VerticalDivisionsEditFieldValueChanged(app, event)
            value = app.VerticalDivisionsEditField.Value;
            app.graph();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.WidthEditField_2Label = uilabel(app.UIFigure);
            app.WidthEditField_2Label.Text = 'Width';
            app.WidthEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.WidthEditField_2.ValueChangedFcn = createCallbackFcn(app, @WidthEditField_2ValueChanged, true);
            app.HeightEditFieldLabel = uilabel(app.UIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @HeightEditFieldValueChanged, true);
            app.HorizontalDivisionsEditFieldLabel = uilabel(app.UIFigure);
            app.HorizontalDivisionsEditFieldLabel.Text = 'Horizontal Divisions';
            app.HorizontalDivisionsEditField = uieditfield(app.UIFigure, 'numeric');
            app.HorizontalDivisionsEditField.ValueChangedFcn = createCallbackFcn(app, @HorizontalDivisionsEditFieldValueChanged, true);
            app.HorizontalDivisionsEditField.Value = 1;
            app.VerticalDivisionsEditFieldLabel = uilabel(app.UIFigure);
            app.VerticalDivisionsEditFieldLabel.Text = 'Vertical Divisions';
            app.VerticalDivisionsEditField = uieditfield(app.UIFigure, 'numeric');
            app.VerticalDivisionsEditField.ValueChangedFcn = createCallbackFcn(app, @VerticalDivisionsEditFieldValueChanged, true);
            app.VerticalDivisionsEditField.Value = 1;
            app.PlaneDimensionsLabel = uilabel(app.UIFigure);
            app.PlaneDimensionsLabel.Text = 'Plane Dimensions';
            app.MeshOptionsLabel = uilabel(app.UIFigure);
            app.MeshOptionsLabel.Text = 'Mesh Options';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlaneStress

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end